classdef HCHPEXOv1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        txtCommand                   matlab.ui.control.TextArea
        AssistiveTorqueCommandLabel  matlab.ui.control.Label
        RBSE10Label                  matlab.ui.control.Label
        ProgramStatusLabel           matlab.ui.control.Label
        ddwSubject                   matlab.ui.control.DropDown
        SubjectDropDownLabel         matlab.ui.control.Label
        ddwCOM                       matlab.ui.control.DropDown
        COMLabel                     matlab.ui.control.Label
        ddwRTG                       matlab.ui.control.DropDown
        RTGStrategyDropDownLabel     matlab.ui.control.Label
        ddwUID                       matlab.ui.control.DropDown
        UIDStrategyDropDownLabel     matlab.ui.control.Label
        FricCompenLabel              matlab.ui.control.Label
        chBoxFricCom                 matlab.ui.control.CheckBox
        txtMode                      matlab.ui.control.TextArea
        WearerModeLabel              matlab.ui.control.Label
        txtInfo                      matlab.ui.control.TextArea
        SensorInfoTextAreaLabel      matlab.ui.control.Label
        txtStatus                    matlab.ui.control.TextArea
        btnReset                     matlab.ui.control.Button
        btnStop                      matlab.ui.control.Button
        btnRun                       matlab.ui.control.StateButton
        sLabel                       matlab.ui.control.Label
        HzLabel                      matlab.ui.control.Label
        edtRunTime                   matlab.ui.control.NumericEditField
        ExpectedRunningTimeLabel     matlab.ui.control.Label
        edtFre                       matlab.ui.control.NumericEditField
        HighLevelFrequencyEditFieldLabel  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
    end

    
    properties (Access = public)
        cellArrayTextStatus % Text to show running status of high-level program
    end
    
    methods (Access = public)
        
         function outPutStatus(app,str)
            string = sprintf('%s\n', str);  % Transfer input str to 'string'
            app.cellArrayTextStatus = horzcat(app.cellArrayTextStatus,string); % Serial connect the string array for display
            app.txtStatus.Value = app.cellArrayTextStatus;  % Set the display
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: btnRun
        function btnRunValueChanged(app, event)
            global ExoP;
            global TempApp;
            TempApp = app;
            value = app.btnRun.Value;
            friCompenValue = app.chBoxFricCom.Value;  % fric_comp flag
            UIDstrategyMode = app.ddwUID.Value;       % UID strategy mode
            RTGstrategyMode = app.ddwRTG.Value;       % RTG strategy mode
            SubjectNumber = app.ddwSubject.Value;     % Selected subject
            COMPort = app.ddwCOM.Value;               % COM Port
            app.txtInfo.Value = ' ';
            app.txtMode.Value = ' ';
            app.txtCommand.Value = ' ';
            app.cellArrayTextStatus = [];
            outPutStatus(app,'****Program Status****');
            if value == true
                ExoP = Set_Parameters();
                try                    
                    ExoP.MainFreq = get(app.edtFre,'value');
                    ExoP.MaxRunTime = get(app.edtRunTime,'value');
                    ExoP.fricEnable = friCompenValue;
                    ExoP.UIDStrategy = UIDstrategyMode;
                    ExoP.UID = UIDInit(UIDstrategyMode);
                    ExoP.RTGStrategy = RTGstrategyMode;
                    ExoP.RTG = RTGInit(RTGstrategyMode);
                    ExoP.SubjectNum = SubjectNumber;
                    ExoP.Subject = SubjectInit(SubjectNumber);
                    ExoP.McuPort = ['COM',num2str(COMPort)];
                catch
                    set(app.btnRun,'value',0);
                    errordlg('Error in input');
                    return;
                end
                outPutStatus(app,'Program Start.');
                pause(5/1000);
                EXOmain();
            end
        end

        % Button pushed function: btnStop
        function btnStopButtonPushed(app, event)
            % Should click after see High-level Control Running (Into timer loop)
            global ExoP;
            set(app.btnRun,'value',0);
%             pause(5/1000);
            if(~isempty(ExoP.config{2,1}) && isvalid(ExoP.config{2,1}))
                ExoP.stopFlag = 1;
                stop(ExoP.config{2,1});
            end            
        end

        % Button pushed function: btnReset
        function btnResetButtonPushed(app, event)
            global ExoP;
            value = app.btnRun.Value;
            if value ~= true
                ExoP = Set_Parameters();
                app.txtInfo.Value = ' ';
                app.txtMode.Value = ' ';
                app.txtCommand.Value = ' ';
                app.cellArrayTextStatus = [];
                outPutStatus(app,'****Program Status****');
                set(app.btnRun,'value',0);
                % % Here is to reset friction compensation
                % set(app.chBoxFricCom,'value',0);
                % % Here is to reset UID and RTG strategy selection
                % set(app.ddwUID,'value',1);
                % set(app.ddwRTG,'value',1);
                % % Here is to reset serial com port
                % set(app.ddwCOM,'value',3);
                % % Here is to reset the subject
                % set(app.ddwSubject,'value',1);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'background1.png';
            app.HighLevelFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.HighLevelFrequencyEditFieldLabel.Text = 'High-Level Frequency';
            app.edtFre = uieditfield(app.UIFigure, 'numeric');
            app.edtFre.Limits = [1 200];
            app.edtFre.Value = 1;
            app.ExpectedRunningTimeLabel = uilabel(app.UIFigure);
            app.ExpectedRunningTimeLabel.Text = 'Expected Running Time';
            app.edtRunTime = uieditfield(app.UIFigure, 'numeric');
            app.edtRunTime.Limits = [1 Inf];
            app.edtRunTime.Value = 1;
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's';
            app.btnRun = uibutton(app.UIFigure, 'state');
            app.btnRun.ValueChangedFcn = createCallbackFcn(app, @btnRunValueChanged, true);
            app.btnRun.Text = 'Run';
            app.btnStop = uibutton(app.UIFigure, 'push');
            app.btnStop.ButtonPushedFcn = createCallbackFcn(app, @btnStopButtonPushed, true);
            app.btnStop.Text = 'Stop';
            app.btnReset = uibutton(app.UIFigure, 'push');
            app.btnReset.ButtonPushedFcn = createCallbackFcn(app, @btnResetButtonPushed, true);
            app.btnReset.Text = 'RESET';
            app.txtStatus = uitextarea(app.UIFigure);
            app.txtStatus.Editable = 'off';
            app.txtStatus.Value = {'****Program Status****'};
            app.SensorInfoTextAreaLabel = uilabel(app.UIFigure);
            app.SensorInfoTextAreaLabel.Text = 'Sensor Info';
            app.txtInfo = uitextarea(app.UIFigure);
            app.txtInfo.Editable = 'off';
            app.WearerModeLabel = uilabel(app.UIFigure);
            app.WearerModeLabel.Text = 'Wearer Mode';
            app.txtMode = uitextarea(app.UIFigure);
            app.txtMode.Editable = 'off';
            app.chBoxFricCom = uicheckbox(app.UIFigure);
            app.chBoxFricCom.Text = '';
            app.FricCompenLabel = uilabel(app.UIFigure);
            app.FricCompenLabel.Text = 'FricCompen';
            app.UIDStrategyDropDownLabel = uilabel(app.UIFigure);
            app.UIDStrategyDropDownLabel.Text = 'UID Strategy';
            app.ddwUID = uidropdown(app.UIFigure);
            app.ddwUID.Items = {'Basic Strategy', 'Developed Strategy', 'Test Mode'};
            app.ddwUID.ItemsData = [1 2 0];
            app.ddwUID.Value = 1;
            app.RTGStrategyDropDownLabel = uilabel(app.UIFigure);
            app.RTGStrategyDropDownLabel.Text = 'RTG Strategy';
            app.ddwRTG = uidropdown(app.UIFigure);
            app.ddwRTG.Items = {'Impedance Strategy', 'Compen Strategy', 'Vel-based Compen', 'Test Mode'};
            app.ddwRTG.ItemsData = [1 2 3 0];
            app.ddwRTG.Value = 1;
            app.COMLabel = uilabel(app.UIFigure);
            app.COMLabel.Text = 'COM';
            app.ddwCOM = uidropdown(app.UIFigure);
            app.ddwCOM.Items = {'COM3', 'COM4', 'COM5'};
            app.ddwCOM.ItemsData = [3 4 5];
            app.ddwCOM.Value = 3;
            app.SubjectDropDownLabel = uilabel(app.UIFigure);
            app.SubjectDropDownLabel.Text = 'Subject';
            app.ddwSubject = uidropdown(app.UIFigure);
            app.ddwSubject.Items = {'HongPeng', 'Hugo'};
            app.ddwSubject.ItemsData = [1 2];
            app.ddwSubject.Value = 1;
            app.ProgramStatusLabel = uilabel(app.UIFigure);
            app.ProgramStatusLabel.Text = 'Program Status';
            app.RBSE10Label = uilabel(app.UIFigure);
            app.RBSE10Label.Text = 'RBSE 1.0';
            app.AssistiveTorqueCommandLabel = uilabel(app.UIFigure);
            app.AssistiveTorqueCommandLabel.Text = 'Assistive Torque Command';
            app.txtCommand = uitextarea(app.UIFigure);
            app.txtCommand.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HCHPEXOv1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end